classdef GUIApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        BasicImageEditingAppLabel     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        UploadButton                  matlab.ui.control.Button
        BlurButton                    matlab.ui.control.Button
        MirrorImageButton             matlab.ui.control.Button
        TomandJerryAddSubButton       matlab.ui.control.Button
        RotationButton                matlab.ui.control.Button
        MultiplicationButton          matlab.ui.control.Button
        ScalingButton                 matlab.ui.control.Button
        EdgeDetectionButton           matlab.ui.control.Button
        FiltersButton                 matlab.ui.control.Button
        Panel_5                       matlab.ui.container.Panel
        BacktoMainScreenButton_4      matlab.ui.control.Button
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        NegativeButton                matlab.ui.control.Button
        SepiaButton                   matlab.ui.control.Button
        GrayScaleButton               matlab.ui.control.Button
        Panel_4                       matlab.ui.container.Panel
        Image6                        matlab.ui.control.Image
        Image5                        matlab.ui.control.Image
        BacktoMainScreenButton_3      matlab.ui.control.Button
        SelectScalingfactorKnob       matlab.ui.control.Knob
        SelectScalingfactorKnobLabel  matlab.ui.control.Label
        DownscaleButton               matlab.ui.control.Button
        UpscaleButton                 matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        BacktoMainScreenButton        matlab.ui.control.Button
        MultiplyButton                matlab.ui.control.Button
        EnterMultiplicationfactorEditField  matlab.ui.control.NumericEditField
        EnterMultiplicationfactorEditFieldLabel  matlab.ui.control.Label
        Panel_3                       matlab.ui.container.Panel
        BacktoMainScreenButton_2      matlab.ui.control.Button
        RotateButton_2                matlab.ui.control.Button
        SelectRotationAngleKnob       matlab.ui.control.Knob
        SelectRotationAngleKnobLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        ImageComponent matlab.ui.control.Image
    end
    
    methods (Access = private)

    % App startup function
    function startupFcn(app)
        % Set visibility of panels to 'off' at startup
        app.Panel_2.Visible = 'off';
        app.Panel_3.Visible = 'off';
        app.Panel_4.Visible = 'off';
        app.Panel_5.Visible = 'off';
        % Add more panels as needed
    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BlurButton
        function BlurButtonPushed(app, event)
            % Read the image
originalImage = app.Image.ImageSource;

% Define Gaussian blur kernel
kernelSize = 5; % Kernel size (odd number)
sigma = 2; % Standard deviation
blurKernel = fspecial('gaussian', [kernelSize, kernelSize], sigma);

% Apply convolution to blur the image
blurredImage = imfilter(originalImage, blurKernel, 'conv', 'replicate');

% Display original and blurred images
figure;
subplot(1,2,1);
imshow(originalImage);
title('Original Image');
subplot(1,2,2);
imshow(blurredImage);
title('Blurred Image');

        end

        % Button pushed function: FiltersButton
        function FiltersButtonPushed(app, event)
            app.Panel.Visible="off";
            app.Panel_5.Visible= "on";
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
            % Read the image
originalImage = app.Image.ImageSource;

% Convert the image to grayscale
grayImage = rgb2gray(originalImage);

% Define Sobel edge detection kernels
sobelX = [-1 0 1; -2 0 2; -1 0 1];
sobelY = [-1 -2 -1; 0 0 0; 1 2 1];

% Pad the image to handle boundaries
paddedImage = padarray(double(grayImage), [1, 1], 'replicate');

% Get dimensions of the padded image
[m, n] = size(paddedImage);

% Create matrices to store gradient magnitudes and directions
gradientMagnitudes = zeros(m-2, n-2);
gradientDirections = zeros(m-2, n-2);

% Perform convolution with Sobel kernels
for i = 1:m-2
    for j = 1:n-2
        % Extract the region of interest from the padded image
        roi = paddedImage(i:i+2, j:j+2);
        
        % Compute gradients in x and y directions
        gradientX = sum(sum(roi .* sobelX));
        gradientY = sum(sum(roi .* sobelY));
        
        % Compute magnitude and direction of the gradient
        gradientMagnitude = sqrt(gradientX^2 + gradientY^2);
        gradientMagnitudes(i, j) = gradientMagnitude;
        gradientDirection = atan2d(gradientY, gradientX); % Compute direction in degrees
        gradientDirections(i, j) = gradientDirection;
    end
end

% Threshold gradient magnitudes to obtain binary edge map
threshold = 50; % Adjust threshold as needed
binaryEdgeMap = gradientMagnitudes > threshold;

% Display original image and edge map
figure;
subplot(1,2,1);
imshow(originalImage);
title('Original Image');
subplot(1,2,2);
imshow(binaryEdgeMap);
title('Edge Map');

        end

        % Button pushed function: MirrorImageButton
        function MirrorImageButtonPushed(app, event)
            % Read the target image file
img =app.Image.ImageSource;

% Create the reflection matrix for vertical reflection
reflection_matrix = [-1 0 0; 0 1 0; 0 0 1]; % 3x3 matrix

% Apply the reflection transformation
tform = affine2d(reflection_matrix);
mirror_image = imwarp(img, tform);

% Display the original and mirror images side by side
figure;
subplot(1, 2, 1);
imshow(img);
title('Original Image');

subplot(1, 2, 2);
imshow(mirror_image);
title('Mirror Image');

sgtitle('Original vs. Mirror Image');

        end

        % Button pushed function: MultiplicationButton
        function MultiplicationButtonPushed(app, event)
            app.Panel.Visible="off";
            app.Panel_2.Visible= "on";
        end

        % Button pushed function: ScalingButton
        function ScalingButtonPushed(app, event)
            app.Panel.Visible="off";
            app.Panel_4.Visible= "on";
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
            app.Panel.Visible="off";
            app.Panel_3.Visible= "on";
        end

        % Button pushed function: TomandJerryAddSubButton
        function TomandJerryAddSubButtonPushed(app, event)
            % Read the background, objects A, and B
background = imread("../assets/red_carpet.jpg");
A = imread("../assets/tom.png");
B = imread("../assets/jerry.png");

% Image addition
object = A + B;

% Resize background to match the size of object
background = imresize(background, [size(object, 1), size(object, 2)]);

% Initialize new image matrices
Im3 = uint8(zeros(size(object)));
whiteImg = uint8(ones(size(object)));

% Image division
mask = whiteImg ./ object;

% Logical AND
im3 = uint8(mask & background);

% Create a mask image where masked areas are black
maskImage = uint8(ones(size(background)) * 255);
maskImage(im3 == 0) = 0; % Set masked areas to black

% Image multiplication
finalImg = (background .* im3) + object;

% Display original objects, background, mask, and final image
figure;

subplot(2, 3, 1);
imshow(A);
title('Object A');

subplot(2, 3, 2);
imshow(B);
title('Object B');

subplot(2, 3, 3);
imshow(object);
title('Object A + B');

subplot(2, 3, 4);
imshow(background);
title('Background');

subplot(2, 3, 5);
imshow(maskImage);
title('Mask');

subplot(2, 3, 6);
imshow(finalImg);
title('Final Image');


        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
         % Set visibility of panels to 'off' at startup
        app.Panel_2.Visible = 'off';
        app.Panel_3.Visible = 'off';
        app.Panel_4.Visible = 'off';
        app.Panel_5.Visible = 'off';
            
            
            % Open file dialog for image selection
        [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, 'Select an image file');
        
        % Check if a file was selected
        if isequal(filename, 0)
            % User canceled the operation
            return;
        end
        
        % Read the selected image file
        fullFilePath = fullfile(pathname, filename);
        img = imread(fullFilePath);
        
        % Display the uploaded image
        app.Image.ImageSource = img
        

        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            % Read the image
            originalImage = app.Image.ImageSource;
            n=app.EnterMultiplicationfactorEditField.Value;
            % Display the original image
            figure;
            subplot(1, 2, 1);
            imshow(originalImage);
            title('Original Image');
            
            % Perform multiplication
            multipliedImage = originalImage * n; % Multiply by 2 for demonstration purposes
            
            % Display the multiplied image
            subplot(1, 2, 2);
            imshow(multipliedImage);
            title('Multiplied Image');
            
            % Save the original and multiplied images
            imwrite(originalImage, 'original_image.jpg'); % Save original image as 'original_image.jpg'
            imwrite(multipliedImage, 'multiplied_image.jpg'); % Save multiplied image as 'multiplied_image.jpg'
        end

        % Button pushed function: BacktoMainScreenButton
        function BacktoMainScreenButtonPushed(app, event)
            app.Panel_2.Visible= "off";
            app.Panel.Visible="on";
            
        end

        % Button pushed function: BacktoMainScreenButton_2
        function BacktoMainScreenButton_2Pushed(app, event)
            app.Panel_3.Visible= "off";
            app.Panel.Visible="on";
            
        end

        % Button pushed function: RotateButton_2
        function RotateButton_2Pushed(app, event)
           % Read the image
originalImage = app.Image.ImageSource;

% Convert angle from degrees to radians
theta = app.SelectRotationAngleKnob.Value;
theta_rad = deg2rad(theta);

% Define rotation matrix
R = [cos(theta_rad), -sin(theta_rad); sin(theta_rad), cos(theta_rad)];

% Get size of original image
[m, n, ~] = size(originalImage);

% Define center of rotation
center_x = n / 2;
center_y = m / 2;

% Create grid of coordinates
[X, Y] = meshgrid(1:n, 1:m);

% Shift coordinates to have origin at center of image
X_shifted = X - center_x;
Y_shifted = Y - center_y;

% Apply rotation transformation
rotated_coordinates = R * [X_shifted(:)'; Y_shifted(:)'];
X_rotated = reshape(rotated_coordinates(1, :), size(X));
Y_rotated = reshape(rotated_coordinates(2, :), size(Y));

% Shift coordinates back
X_rotated = X_rotated + center_x;
Y_rotated = Y_rotated + center_y;

% Interpolate to get rotated image
rotatedImage = zeros(size(originalImage), 'uint8');
for channel = 1:3
    rotatedImage(:, :, channel) = interp2(X, Y, double(originalImage(:, :, channel)), X_rotated, Y_rotated, 'linear', 0);
end

% Display the original and rotated images
subplot(1, 2, 1);
imshow(originalImage);
title('Original Image');

subplot(1, 2, 2);
imshow(rotatedImage);
title('Rotated Image');


        end

        % Button pushed function: BacktoMainScreenButton_3
        function BacktoMainScreenButton_3Pushed(app, event)
            app.Panel_4.Visible= "off";
            app.Panel.Visible="on";
        end

        % Button pushed function: UpscaleButton
        function UpscaleButtonPushed(app, event)
                        
            % Read the image
originalImage = app.Image.ImageSource;

% Scale factors
scaleFactorX = app.SelectScalingfactorKnob.Value; % Scale factor for width
scaleFactorY = app.SelectScalingfactorKnob.Value; % Scale factor for height

% Dimensions of original image
[rows, cols, ~] = size(originalImage);

% Dimensions of scaled image
newRows = round(rows * scaleFactorY);
newCols = round(cols * scaleFactorX);

% Initialize scaled image
scaledImage = zeros(newRows, newCols, 3, 'uint8');

% Nearest neighbor interpolation
for i = 1:newRows
    for j = 1:newCols
        % Corresponding coordinates in original image
        x = round(i / scaleFactorY);
        y = round(j / scaleFactorX);
        
        % Assign pixel value using nearest neighbor interpolation
        scaledImage(i, j, :) = originalImage(min(x, rows), min(y, cols), :);
    end
end

% Display original and scaled images
% Create a figure
figure;

% Display the original image
subplot(1, 2, 1);
imshow(originalImage);
title('Original Image');

% Calculate the width ratio between the two images
width_ratio = size(originalImage, 2) / size(scaledImage, 2);

% Display the scaled image with adjusted width
subplot(1, 2, 2);
imshow(scaledImage);
title('Upscaled Image');
pos = get(gca, 'Position');
pos(3) = pos(3) * width_ratio;
set(gca, 'Position', pos);

        end

        % Button pushed function: DownscaleButton
        function DownscaleButtonPushed(app, event)
             % Read the image
            originalImage =app.Image.ImageSource;
            
            % Scale factors
            scaleFactorX = app.SelectScalingfactorKnob.Value; % Scale factor for width
            scaleFactorY = app.SelectScalingfactorKnob.Value; % Scale factor for height
            
            % Dimensions of original image
            [rows, cols, ~] = size(originalImage);
            
            % Dimensions of scaled image
            newRows = round(rows * scaleFactorY);
            newCols = round(cols * scaleFactorX);
            
            % Initialize scaled image
            scaledImage = zeros(newRows, newCols, 3, 'uint8');
            
            % Nearest neighbor interpolation
            for i = 1:newRows
                for j = 1:newCols
                    % Corresponding coordinates in original image
                    x = round(i/scaleFactorX);
                    y = round(j/scaleFactorY);
                    
                    % Assign pixel value
                    scaledImage(i, j, :) = originalImage(min(x, rows), min(y, cols), :);
                end
            end
            imwrite(scaledImage, "Downscaled.jpeg");
            scaledImage = imread("Downscaled.jpeg");
            
            % Display original and scaled images
            % Create a figure
            figure;
            
            % Display the original image
            subplot(1, 2, 1);
            imshow(originalImage);
            title('Original Image');
            
            % Calculate the width ratio between the two images
            width_ratio = size(originalImage, 2) / size(scaledImage, 2);
            
            % Display the scaled image with adjusted width
            subplot(1, 2, 2);
            imshow(scaledImage);
            title('DownScaled Image');
            pos = get(gca, 'Position');
            pos(3) = pos(3) * width_ratio;
            set(gca, 'Position', pos);
        end

        % Button pushed function: GrayScaleButton
        function GrayScaleButtonPushed(app, event)
            % Read the PNG file
            input_image = app.Image.ImageSource;
            
            % Check if the input image is RGB or grayscale
            if size(input_image, 3) == 3
                % Convert RGB image to grayscale using linear algebra
                grayscale_image = double(input_image(:,:,1)) * 0.2989 + double(input_image(:,:,2)) * 0.5870 + double(input_image(:,:,3)) * 0.114;
            else
                % If the input image is already grayscale, just copy it
                grayscale_image = input_image;
            end
            
            % Convert the grayscale image to uint8 data type
            grayscale_image = uint8(grayscale_image);
            
            % Save the grayscale image
            imwrite(grayscale_image, 'grayscale_image.jpg');
            disp('Conversion to grayscale completed and saved as grayscale_image.jpg');
            
            % Display original and grayscale images side by side
            figure;
            subplot(1, 2, 1);
            imshow(input_image);
            title('Original Image');
            
            subplot(1, 2, 2);
            imshow(grayscale_image);
            title('Grayscale Image');
            
            sgtitle('Original vs. Grayscale Image');

        end

        % Button pushed function: SepiaButton
        function SepiaButtonPushed(app, event)
            % Read the input RGB image
            inputImage = app.Image.ImageSource;
            
            % Sepia transformation matrix
            sepiaMatrix = [0.393 0.769 0.189; ...
                           0.349 0.686 0.168; ...
                           0.272 0.534 0.131];
            
            % Apply the sepia transformation to each pixel
            sepiaImage = zeros(size(inputImage));
            
            for i = 1:size(inputImage, 1)
                for j = 1:size(inputImage, 2)
                    pixel = double(reshape(inputImage(i, j, :), 1, [])); % Reshape to row vector
                    sepiaPixel = pixel * sepiaMatrix';   % Apply sepia transformation
                    sepiaImage(i, j, :) = sepiaPixel;   % Assign the transformed value to the pixel
                end
            end
            
            % Clip values to range [0, 255]
            sepiaImage(sepiaImage > 255) = 255;
            
            % Convert back to uint8
            sepiaImage_uint8 = uint8(sepiaImage);
            
            % Display the original and sepia images
            figure;
            subplot(1, 2, 1);
            imshow(inputImage);
            title('Original Image');
            
            subplot(1, 2, 2);
            imshow(sepiaImage_uint8);
            title('Sepia Filtered Image');
            
            % Save the sepia image
            imwrite(sepiaImage_uint8, 'sepia_image.jpg');

        end

        % Button pushed function: NegativeButton
        function NegativeButtonPushed(app, event)
            % Read the input RGB image
            inputImage = app.Image.ImageSource;
            
            % Apply the negative filter to each pixel
            negativeImage = 255 - inputImage;
            
            % Display the original and negative images
            figure;
            subplot(1, 2, 1);
            imshow(inputImage);
            title('Original Image');
            
            subplot(1, 2, 2);
            imshow(negativeImage);
            title('Negative Filtered Image');
            
            % Save the negative image
            imwrite(negativeImage, 'negative_image.jpg');

        end

        % Button pushed function: BacktoMainScreenButton_4
        function BacktoMainScreenButton_4Pushed(app, event)
            app.Panel_5.Visible= "off";
            app.Panel.Visible="on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SelectRotationAngleKnobLabel = uilabel(app.Panel_3);
            app.SelectRotationAngleKnobLabel.Text = 'Select Rotation Angle ';
            app.SelectRotationAngleKnob = uiknob(app.Panel_3, 'continuous');
            app.SelectRotationAngleKnob.Limits = [0 360];
            app.RotateButton_2 = uibutton(app.Panel_3, 'push');
            app.RotateButton_2.ButtonPushedFcn = createCallbackFcn(app, @RotateButton_2Pushed, true);
            app.RotateButton_2.Text = 'Rotate';
            app.BacktoMainScreenButton_2 = uibutton(app.Panel_3, 'push');
            app.BacktoMainScreenButton_2.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainScreenButton_2Pushed, true);
            app.BacktoMainScreenButton_2.Text = 'Back to Main Screen ';
            app.Panel_2 = uipanel(app.UIFigure);
            app.EnterMultiplicationfactorEditFieldLabel = uilabel(app.Panel_2);
            app.EnterMultiplicationfactorEditFieldLabel.Text = 'Enter Multiplication factor ';
            app.EnterMultiplicationfactorEditField = uieditfield(app.Panel_2, 'numeric');
            app.MultiplyButton = uibutton(app.Panel_2, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.BacktoMainScreenButton = uibutton(app.Panel_2, 'push');
            app.BacktoMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainScreenButtonPushed, true);
            app.BacktoMainScreenButton.Text = 'Back to Main Screen ';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UpscaleButton = uibutton(app.Panel_4, 'push');
            app.UpscaleButton.ButtonPushedFcn = createCallbackFcn(app, @UpscaleButtonPushed, true);
            app.UpscaleButton.Text = 'Upscale';
            app.DownscaleButton = uibutton(app.Panel_4, 'push');
            app.DownscaleButton.ButtonPushedFcn = createCallbackFcn(app, @DownscaleButtonPushed, true);
            app.DownscaleButton.Text = 'Downscale';
            app.SelectScalingfactorKnobLabel = uilabel(app.Panel_4);
            app.SelectScalingfactorKnobLabel.Text = 'Select Scaling factor ';
            app.SelectScalingfactorKnob = uiknob(app.Panel_4, 'continuous');
            app.SelectScalingfactorKnob.Limits = [0 10];
            app.BacktoMainScreenButton_3 = uibutton(app.Panel_4, 'push');
            app.BacktoMainScreenButton_3.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainScreenButton_3Pushed, true);
            app.BacktoMainScreenButton_3.Text = 'Back to Main Screen';
            app.Image5 = uiimage(app.Panel_4);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'UpscaleIconWhite.jpg');
            app.Image6 = uiimage(app.Panel_4);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'DownScaleWhiteIcon.jpg');
            app.Panel_5 = uipanel(app.UIFigure);
            app.GrayScaleButton = uibutton(app.Panel_5, 'push');
            app.GrayScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayScaleButtonPushed, true);
            app.GrayScaleButton.Text = 'GrayScale';
            app.SepiaButton = uibutton(app.Panel_5, 'push');
            app.SepiaButton.ButtonPushedFcn = createCallbackFcn(app, @SepiaButtonPushed, true);
            app.SepiaButton.Text = 'Sepia';
            app.NegativeButton = uibutton(app.Panel_5, 'push');
            app.NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeButtonPushed, true);
            app.NegativeButton.Text = 'Negative';
            app.Image2 = uiimage(app.Panel_5);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'DisplayBandW.jpg');
            app.Image3 = uiimage(app.Panel_5);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'DisplaySepia.jpg');
            app.Image4 = uiimage(app.Panel_5);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'DisplayNegative.jpg');
            app.BacktoMainScreenButton_4 = uibutton(app.Panel_5, 'push');
            app.BacktoMainScreenButton_4.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainScreenButton_4Pushed, true);
            app.BacktoMainScreenButton_4.Text = 'Back to Main Screen';
            app.Panel = uipanel(app.UIFigure);
            app.FiltersButton = uibutton(app.Panel, 'push');
            app.FiltersButton.ButtonPushedFcn = createCallbackFcn(app, @FiltersButtonPushed, true);
            app.FiltersButton.Text = 'Filters';
            app.EdgeDetectionButton = uibutton(app.Panel, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.ScalingButton = uibutton(app.Panel, 'push');
            app.ScalingButton.ButtonPushedFcn = createCallbackFcn(app, @ScalingButtonPushed, true);
            app.ScalingButton.Text = 'Scaling';
            app.MultiplicationButton = uibutton(app.Panel, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicationButtonPushed, true);
            app.MultiplicationButton.Text = 'Multiplication';
            app.RotationButton = uibutton(app.Panel, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.TomandJerryAddSubButton = uibutton(app.Panel, 'push');
            app.TomandJerryAddSubButton.ButtonPushedFcn = createCallbackFcn(app, @TomandJerryAddSubButtonPushed, true);
            app.TomandJerryAddSubButton.Text = 'Tom and Jerry AddSub';
            app.MirrorImageButton = uibutton(app.Panel, 'push');
            app.MirrorImageButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorImageButtonPushed, true);
            app.MirrorImageButton.Text = 'Mirror Image';
            app.BlurButton = uibutton(app.Panel, 'push');
            app.BlurButton.ButtonPushedFcn = createCallbackFcn(app, @BlurButtonPushed, true);
            app.BlurButton.Text = 'Blur';
            app.UploadButton = uibutton(app.Panel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.Image = uiimage(app.Panel);
            app.BasicImageEditingAppLabel = uilabel(app.Panel);
            app.BasicImageEditingAppLabel.Text = 'Basic Image Editing App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end